classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        TextArea_10                 matlab.ui.control.TextArea
        Label_15                    matlab.ui.control.Label
        TextArea_9                  matlab.ui.control.TextArea
        Label_14                    matlab.ui.control.Label
        TextArea_8                  matlab.ui.control.TextArea
        Label_12                    matlab.ui.control.Label
        TextArea_7                  matlab.ui.control.TextArea
        Label_11                    matlab.ui.control.Label
        TextArea_5                  matlab.ui.control.TextArea
        Button_2                    matlab.ui.control.Button
        KTextArea                   matlab.ui.control.TextArea
        KTextAreaLabel              matlab.ui.control.Label
        TextArea_3                  matlab.ui.control.TextArea
        Label_10                    matlab.ui.control.Label
        DropDown_3                  matlab.ui.control.DropDown
        Label_9                     matlab.ui.control.Label
        TextArea_2                  matlab.ui.control.TextArea
        Label_8                     matlab.ui.control.Label
        dBTextArea_3                matlab.ui.control.TextArea
        dBTextArea_3Label           matlab.ui.control.Label
        dBTextArea_2                matlab.ui.control.TextArea
        dBTextArea_2Label           matlab.ui.control.Label
        dBTextArea                  matlab.ui.control.TextArea
        dBTextAreaLabel             matlab.ui.control.Label
        HzTextArea_2                matlab.ui.control.TextArea
        HzLabel_2                   matlab.ui.control.Label
        HzTextArea                  matlab.ui.control.TextArea
        HzLabel                     matlab.ui.control.Label
        DropDown_2                  matlab.ui.control.DropDown
        Label_7                     matlab.ui.control.Label
        spssamplepersymbolDropDown  matlab.ui.control.DropDown
        spssamplepersymbolLabel     matlab.ui.control.Label
        Tree                        matlab.ui.container.CheckBoxTree
        Node                        matlab.ui.container.TreeNode
        BPSKNode                    matlab.ui.container.TreeNode
        QPSKNode                    matlab.ui.container.TreeNode
        PSKNode                     matlab.ui.container.TreeNode
        QAMNode                     matlab.ui.container.TreeNode
        QAMNode_2                   matlab.ui.container.TreeNode
        QAMNode_3                   matlab.ui.container.TreeNode
        QAMNode_4                   matlab.ui.container.TreeNode
        ASKNode                     matlab.ui.container.TreeNode
        ASKNode_2                   matlab.ui.container.TreeNode
        FSKNode                     matlab.ui.container.TreeNode
        FSKNode_2                   matlab.ui.container.TreeNode
        PAM4Node                    matlab.ui.container.TreeNode
        PAM8Node                    matlab.ui.container.TreeNode
        PAM16Node                   matlab.ui.container.TreeNode
        Button                      matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        DropDown                    matlab.ui.control.DropDown
        Label_4                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            app.dBTextArea_3.Value=app.TextArea.Value;
        end

        % Callback function
        function ButtonPushed2(app, event)
            source_data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            filesavepath = uigetdir('*.m');          % 用户选择文件
            if isequal(filesavepath,0)
                disp('User selected Cancel');
            end
            if filesavepath~=0
                app.TextArea.Value=filesavepath;
            end
            %path = uigetdir();                            % 用户选择文件夹，取消返回0             
            %[path, name, surffix] = fileparts(fullpath);  % 拆分。
        end

        % Button pushed function: Button
        function ButtonPushed3(app, event)
            global flag
            flag=1;
%             temp=dataset_gen(app.spssamplepersymbolDropDown.Value,app.DropDown_2.Value, ...
%                 app.HzTextArea.Value,app.HzTextArea_2.Value,app.dBTextArea.Value,app.dBTextArea_2.Value, ...
%                 app.dBTextArea_3.Value,app.TextArea_3.Value,app.KTextArea.Value,...
%                 app.TextArea_7.Value, app.TextArea_8.Value,app.TextArea_2.Value, ...
%                 app.TextArea.Value,app.TextArea_5.Value,flag);
            %设置参数
            CheckedNodes=app.Tree.CheckedNodes;
            modulationTypes=categorical();  
            if ~isempty(CheckedNodes)
                if CheckedNodes(1).Text=="调制信号类型选择"
                    CheckedNodes(1)=[];
                    for i=1:length(CheckedNodes)
                        modulationTypes = categorical([modulationTypes,cellstr(CheckedNodes(i).Text)]);    
                    end
                else
                    for i=1:length(CheckedNodes)
                        modulationTypes = categorical([modulationTypes,cellstr(CheckedNodes(i).Text)]);    
                    end
                end
            fig = uifigure(1);
            d = uiprogressdlg(fig,'Title','请稍等',...
                'Message','正在制作数据集');
            sps=str2num(app.spssamplepersymbolDropDown.Value);
            spf=str2num(app.DropDown_2.Value);
            fs=char(app.HzTextArea.Value);fs=str2num(fs);
            fc=char(app.HzTextArea_2.Value);fc=str2num(fc);
            first=char(app.dBTextArea.Value);first=str2num(first);
            last=char(app.dBTextArea_2.Value);last=str2num(last);
            foot=char(app.dBTextArea_3.Value);foot=str2num(foot);
            KFactor=char(app.KTextArea.Value);KFactor=str2num(KFactor);
            MaximumClockOffset=char(app.TextArea_3.Value);MaximumClockOffset=str2num(MaximumClockOffset);
            PathDelays=char(app.TextArea_7.Value);PathDelays=str2num(PathDelays);
            AveragePathGains=char(app.TextArea_8.Value);AveragePathGains=str2num(AveragePathGains);
            numFramesPerModType=char(app.TextArea_2.Value);numFramesPerModType=str2num(numFramesPerModType);
            filepath=char(app.TextArea.Value);
            filename_ori=char(app.TextArea_5.Value);    
            %test(CheckedNodes)
%             if ~isempty(CheckedNodes)
%                 temp="请先选择调制信号类型";
%                 app.TextArea_10.Value=temp;
%             elseif CheckedNodes(1).Text=="调制信号类型选择"
%                 CheckedNodes(1)=[];
%                 for i=1:length(CheckedNodes)
%                     modulationTypes = categorical([modulationTypes,cellstr(CheckedNodes(i).Text)]);    
%                 end
%             else
%                 for i=1:length(CheckedNodes)
%                     modulationTypes = categorical([modulationTypes,cellstr(CheckedNodes(i).Text)]);    
%                 end    
%             end
            
                
%             else 
%                 temp="请先选择调制信号类型";
%                 app.TextArea_10.Value=temp;
%             end
              
            %CheckedNodes=CheckedNodes(2:);
            %checkedNodes=categorical(app.Tree.CheckedNodes);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%选择需要的信号种类%%%%%%%%%%%%%%%%%%%%%%%%%%%;
            %modulationTypes = categorical(["BPSK","8PSK","16QAM","64QAM","PAM4","PAM8"]);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%自定义参数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % numFramesPerModType = 2;                       % 每种调制方式在每种信噪比下的样本个数
            % sps = 8;                                       % 每个符号的采样点
            % spf = 512;                                     % 单个样本长度（帧长度）
            % fs = 500e3;                                    % 采样率
            % fc=902e6                                       % 中心频点
            % first=0;                                       % 起始信噪比                     
            % last=30;                                       % 截止信噪比
            % KFactor=4                                      % 莱斯信道K因子
            % foot=2;                                        % 信噪比跨度
            % MaximumClockOffset=5;                          % 时钟偏移因子                                                     
            % PathDelays=[0 1.8 3.4];                        % 多径时延
            % AveragePathGains=[0 -2 -10];                   % 多径增益
            % filepath='E:\xuqiang\filesavepath';
            % filename_ori='Sps4Len128Fs200e3Fc902e6Num1000Snr[0,2,30]Complex';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%可变参数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            numModulationTypes = length(modulationTypes);    % 获取信号种类数目
            filename=filepath+"\"+filename_ori+".h5";
            snr_num=(last-first)/foot+1;                     % 信噪比总数
            co=0.5;
            q=0;
            %%%%%%%%%%检测文件是否存在，若存在则更改文件名%%%%%%%%%%
            if ~exist('filename','file')~=0
               filename=filepath+"\"+filename_ori+datestr(clock,'yyyy-mm-dd-HH-MM-SS' )+".h5";
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%创建H5文件%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h5create(filename,'/ModData',[Inf 2 spf],'Datatype','double', ...
                       'ChunkSize',[1 2 spf],'Deflate',9)
            h5create(filename,'/ModType',[Inf numModulationTypes],'Datatype','int8', ...
                       'ChunkSize',[1 numModulationTypes],'Deflate',9)
            h5create(filename,'/Snr',[Inf],'Datatype','int8', ...
                       'ChunkSize',[1],'Deflate',9)
            %创建信道
            channel=helperModClassTestChannel(...
                'SampleRate',fs,...
                'SNR',30,...
                'PathDelays',PathDelays/fs,...
                'AveragePathGains',AveragePathGains,...
                'MaximumDopplerShift',4,...
                'KFactor',KFactor,...
                'MaximumClockOffset',MaximumClockOffset,...
                'CenterFrequency',fc);
            tic 
            %最终截取信号的偏移量
            transDelay = 50;
            index = numFramesPerModType*snr_num;
            total=numFramesPerModType*snr_num*numModulationTypes;
            for modType = 1:numModulationTypes
                p=1;                               % 循环计数
                data=zeros(index,2,spf);
                one_hot_code1=int8(zeros(index,numModulationTypes));
                snr1=int8(zeros(index,1));
                %输出当前信号生成的信息,包括时间，产生的帧
                %fprintf('%s - Generating %s frames\n', ...
                %  datestr(toc/86400,'HH:MM:SS'), modulationTypes(modType))
                temp="正在生成 "+char(modulationTypes(modType))+"信号 "+datestr(clock,'yyyy-mm-dd-HH:MM:SS' );
                d.Message = temp;
                app.TextArea_10.Value=temp;
                pause(1)
                %信号标签
                hotcode=int8(zeros(1,numModulationTypes));
                hotcode(modType)=1;
                %产生个数相同的数字符号
                dataSrc = helperModClassGetSource(modulationTypes(modType), sps, 2*spf, fs);
                %产生调制器
                modulator = helperModClassGetModulator(modulationTypes(modType), sps, fs,co);
                for snr=linspace(first,last,snr_num)
                    channel.SNR=snr;
                    %开始产生信号
                    for j=1:numFramesPerModType    
                      if (flag == 0)
                          break;
                      end
                      drawnow()
                      % 生成随机信号
                      x = dataSrc();
                      % 调制
                      y = modulator(x);
                      %rxSamples=awgn(y,snr,"measured");
                      rxSamples = channel(y);
                      frame = helperModClassFrameGenerator(rxSamples, spf, spf, transDelay, sps);
                      frame = frame.';
                      Idata=real(frame);
                      Qdata=imag(frame);
                      ori_data=[Idata;Qdata];
                      final=reshape(ori_data,[1 2 spf]);
                      data(p,:,:)=final;        
                      one_hot_code1(p,:)=hotcode;
                      snr1(p)=int8(snr); 
                      d.Value = (p+q*index)/total;
                      %d.Message = "当前进度"+num2str(d.Value*100)+"%"+"("+num2str(p+q*index)+"/"+num2str(total)+")";
                      d.Message = "当前进度"+num2str(d.Value*100)+"%";
                      p=p+1;
                    end
                end
                startx = [1+q*index 1 1];
                countx = [index 2 spf];          
                starty = [1+q*index 1];
                county = [index numModulationTypes];           
                startz = 1+q*index;
                countz = index; 
                h5write(filename,'/ModData',data,startx,countx);
                h5write(filename,'/ModType',one_hot_code1,starty,county);
                h5write(filename,'/Snr',snr1,startz,countz);
                q=q+1;
            end
            if flag==1
                temp="数据集生成完毕 "+datestr(clock,'yyyy-mm-dd-HH:MM:SS' );
                app.TextArea_10.Value=temp;
            else
                temp="终止运行 "+datestr(clock,'yyyy-mm-dd-HH:MM:SS' );
                app.TextArea_10.Value=temp;
            end
            close(fig);
            close(d);
            else 
%                 h=msgbox("请先选择调制信号类型 "+datestr(clock,'yyyy-mm-dd-HH:MM:SS' ));
%                 % 修改字体
%                 ah = get( h, 'CurrentAxes' );
%                 ch = get( ah, 'Children' );
%                 set( ch, 'FontSize', 2 );     
%                 set(h,'Position',[500 200 100 100]);
                temp="请先选择调制信号类型 "+datestr(clock,'yyyy-mm-dd-HH:MM:SS' );
                app.TextArea_10.Value=temp;
            end
        end

        % Drop down opening function: DropDown_3
        function DropDown_3Opening(app, event)
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Value changed function: TextArea_5
        function TextArea_5ValueChanged(app, event)
            value = app.TextArea_5.Value;
        end

        % Value changed function: spssamplepersymbolDropDown
        function spssamplepersymbolDropDownValueChanged(app, event)
            value = app.spssamplepersymbolDropDown.Value;
            
        end

        % Callback function
        function Button_3Pushed(app, event)
            if app.DropDown.Value=="仿真数据"
                if app.DropDown_3.Value=="复杂信道"
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Complex";
                else
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Awgn";
                end   
            else
                    app.TextArea_5.Value="RealData"+"Len"+app.DropDown_2.Value+"Num"+app.TextArea_2.Value;
            end
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Value changed function: HzTextArea
        function HzTextAreaValueChanged(app, event)
            value = app.HzTextArea.Value;
            
        end

        % Value changed function: HzTextArea_2
        function HzTextArea_2ValueChanged(app, event)
            value = app.HzTextArea_2.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Value changed function: dBTextArea
        function dBTextAreaValueChanged(app, event)
            value = app.dBTextArea.Value;
            
        end

        % Value changed function: dBTextArea_2
        function dBTextArea_2ValueChanged(app, event)
            value = app.dBTextArea_2.Value;
            
        end

        % Value changed function: dBTextArea_3
        function dBTextArea_3ValueChanged(app, event)
            value = app.dBTextArea_3.Value;
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value=="复杂信道"
                app.KTextArea.Enable = 'on';
                app.TextArea_3.Enable = 'on';
                app.TextArea_7.Enable = 'on';
                app.TextArea_8.Enable = 'on'; 
            else
                app.KTextArea.Enable = 'off';
                app.TextArea_3.Enable = 'off';
                app.TextArea_7.Enable = 'off';
                app.TextArea_8.Enable = 'off';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value=="实测数据"
                app.spssamplepersymbolDropDown.Enable = 'off';
                app.HzTextArea.Enable = 'off';
                app.HzTextArea_2.Enable = 'off';
                app.TextArea_2.Enable="off"
                app.dBTextArea.Enable = 'off';
                app.dBTextArea_2.Enable = 'off';
                app.dBTextArea_3.Enable = 'off';
                app.DropDown_3.Enable = 'off';
                app.TextArea_3.Enable = 'off';
                app.KTextArea.Enable = 'off';
                app.TextArea_7.Enable = 'off';
                app.TextArea_8.Enable = 'off';
                app.Tree.Enable = 'off';
                app.TextArea_9.Enable="on"
            else
                if app.DropDown_3.Value=="复杂信道"
                    app.spssamplepersymbolDropDown.Enable = 'on';
                    app.HzTextArea.Enable = 'on';
                    app.HzTextArea_2.Enable = 'on';
                    app.dBTextArea.Enable = 'on';
                    app.dBTextArea_2.Enable = 'on';
                    app.dBTextArea_3.Enable = 'on';
                    app.DropDown_3.Enable = 'on';
                    app.TextArea_3.Enable = 'on';
                    app.KTextArea.Enable = 'on';
                    app.TextArea_7.Enable = 'on';
                    app.TextArea_8.Enable = 'on';
                    app.Tree.Enable = 'on';
                    app.TextArea_2.Enable="on";
                    app.TextArea_9.Enable="off";
                    
                 
                else
                    app.spssamplepersymbolDropDown.Enable = 'on';
                    app.HzTextArea.Enable = 'on';
                    app.HzTextArea_2.Enable = 'on';
                    app.dBTextArea.Enable = 'on';
                    app.dBTextArea_2.Enable = 'on';
                    app.dBTextArea_3.Enable = 'on';
                    app.DropDown_3.Enable = 'on';  
                    app.Tree.Enable = 'on';
                    app.TextArea_2.Enable="on";
                    app.TextArea_9.Enable="off"
                end
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            
        end

        % Value changed function: KTextArea
        function KTextAreaValueChanged(app, event)
            value = app.KTextArea.Value;
            
        end

        % Value changed function: TextArea_7
        function TextArea_7ValueChanged(app, event)
            value = app.TextArea_7.Value;
            
        end

        % Value changed function: TextArea_8
        function TextArea_8ValueChanged(app, event)
            value = app.TextArea_8.Value;
            
        end

        % Callback function
        function Button_4Pushed(app, event)
            if app.DropDown.Value=="仿真数据"
                if app.DropDown_3.Value=="复杂信道"
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Complex";
                else
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Awgn";
                end   
            else
                    app.TextArea_5.Value="RealData"+"Len"+app.DropDown_2.Value+"Num"+app.TextArea_2.Value;
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            %assignin('base','checkedNodes',checkedNodes);
        end

        % Value changed function: TextArea_9
        function TextArea_9ValueChanged(app, event)
            value = app.TextArea_9.Value;
            
        end

        % Value changed function: TextArea_10
        function TextArea_10ValueChanged(app, event)
            value = app.TextArea_10.Value;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.DropDown.Value=="仿真数据"
                if app.DropDown_3.Value=="复杂信道"
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Complex";
                else
                    app.TextArea_5.Value="Sps"+app.spssamplepersymbolDropDown.Value+"Len"+app.DropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.TextArea_2.Value...
                    +"Snr["+app.dBTextArea.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Awgn";
                end   
            else
                    app.TextArea_5.Value="RealData"+"Len"+app.DropDown_2.Value+"Num"+app.TextArea_2.Value;
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global flag
            flag=0;

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '仿真、实测数据集制作';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '数据来源';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'仿真数据', '实测数据'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '仿真数据';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'E:\xuqiang\filesavepath'};
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed3, true);
            app.Button.Text = '启动生成数据集';
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '调制信号类型选择';
            app.BPSKNode = uitreenode(app.Node);
            app.BPSKNode.Text = 'BPSK';
            app.QPSKNode = uitreenode(app.Node);
            app.QPSKNode.Text = 'QPSK';
            app.PSKNode = uitreenode(app.Node);
            app.PSKNode.Text = '8PSK';
            app.QAMNode = uitreenode(app.Node);
            app.QAMNode.Text = '16QAM';
            app.QAMNode_2 = uitreenode(app.Node);
            app.QAMNode_2.Text = '64QAM';
            app.QAMNode_3 = uitreenode(app.Node);
            app.QAMNode_3.Text = '128QAM';
            app.QAMNode_4 = uitreenode(app.Node);
            app.QAMNode_4.Text = '256QAM';
            app.ASKNode = uitreenode(app.Node);
            app.ASKNode.Text = '2ASK';
            app.ASKNode_2 = uitreenode(app.Node);
            app.ASKNode_2.Text = '4ASK';
            app.FSKNode = uitreenode(app.Node);
            app.FSKNode.Text = '2FSK';
            app.FSKNode_2 = uitreenode(app.Node);
            app.FSKNode_2.Text = '4FSK';
            app.PAM4Node = uitreenode(app.Node);
            app.PAM4Node.Text = 'PAM4';
            app.PAM8Node = uitreenode(app.Node);
            app.PAM8Node.Text = 'PAM8';
            app.PAM16Node = uitreenode(app.Node);
            app.PAM16Node.Text = 'PAM16';
            app.spssamplepersymbolLabel = uilabel(app.GridLayout);
            app.spssamplepersymbolLabel.Text = 'sps(sample per symbol)';
            app.spssamplepersymbolDropDown = uidropdown(app.GridLayout);
            app.spssamplepersymbolDropDown.Items = {'4', '8', '16', '32', '64'};
            app.spssamplepersymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @spssamplepersymbolDropDownValueChanged, true);
            app.spssamplepersymbolDropDown.Value = '8';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '样本长度';
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_2.Items = {'128', '256', '512', '1024', '2048'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '512';
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = '采样率(Hz)';
            app.HzTextArea = uitextarea(app.GridLayout);
            app.HzTextArea.ValueChangedFcn = createCallbackFcn(app, @HzTextAreaValueChanged, true);
            app.HzTextArea.Value = {'500e3'};
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '中心频率(Hz)';
            app.HzTextArea_2 = uitextarea(app.GridLayout);
            app.HzTextArea_2.ValueChangedFcn = createCallbackFcn(app, @HzTextArea_2ValueChanged, true);
            app.HzTextArea_2.Value = {'902e6'};
            app.dBTextAreaLabel = uilabel(app.GridLayout);
            app.dBTextAreaLabel.Text = '起始信噪比(dB)';
            app.dBTextArea = uitextarea(app.GridLayout);
            app.dBTextArea.ValueChangedFcn = createCallbackFcn(app, @dBTextAreaValueChanged, true);
            app.dBTextArea.Value = {'0'};
            app.dBTextArea_2Label = uilabel(app.GridLayout);
            app.dBTextArea_2Label.Text = '终止信噪比(dB)';
            app.dBTextArea_2 = uitextarea(app.GridLayout);
            app.dBTextArea_2.ValueChangedFcn = createCallbackFcn(app, @dBTextArea_2ValueChanged, true);
            app.dBTextArea_2.Value = {'30'};
            app.dBTextArea_3Label = uilabel(app.GridLayout);
            app.dBTextArea_3Label.Text = '信噪比步进(dB)';
            app.dBTextArea_3 = uitextarea(app.GridLayout);
            app.dBTextArea_3.ValueChangedFcn = createCallbackFcn(app, @dBTextArea_3ValueChanged, true);
            app.dBTextArea_3.Value = {'2'};
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '样本数/信号类型/信噪比';
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Value = {'1'};
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '信道类型';
            app.DropDown_3 = uidropdown(app.GridLayout);
            app.DropDown_3.Items = {'复杂信道', '高斯白噪声信道'};
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_3Opening, true);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '复杂信道';
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '时钟偏移因子';
            app.TextArea_3 = uitextarea(app.GridLayout);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.Value = {'5'};
            app.KTextAreaLabel = uilabel(app.GridLayout);
            app.KTextAreaLabel.Text = '莱斯信道K因子';
            app.KTextArea = uitextarea(app.GridLayout);
            app.KTextArea.ValueChangedFcn = createCallbackFcn(app, @KTextAreaValueChanged, true);
            app.KTextArea.Value = {'4'};
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '设置数据集保存路径';
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.ValueChangedFcn = createCallbackFcn(app, @TextArea_5ValueChanged, true);
            app.TextArea_5.Value = {'Sps8Len512Fs500e3Fc902e6Num1Snr[0,2,30]Complex'};
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = '多径时延';
            app.TextArea_7 = uitextarea(app.GridLayout);
            app.TextArea_7.ValueChangedFcn = createCallbackFcn(app, @TextArea_7ValueChanged, true);
            app.TextArea_7.Value = {'[0 1.8 3.4]'};
            app.Label_12 = uilabel(app.GridLayout);
            app.Label_12.Text = '多径增益';
            app.TextArea_8 = uitextarea(app.GridLayout);
            app.TextArea_8.ValueChangedFcn = createCallbackFcn(app, @TextArea_8ValueChanged, true);
            app.TextArea_8.Value = {'[0 -2 -10]'};
            app.Label_14 = uilabel(app.GridLayout);
            app.Label_14.Enable = 'off';
            app.Label_14.Text = '每包数据选取样本数';
            app.TextArea_9 = uitextarea(app.GridLayout);
            app.TextArea_9.ValueChangedFcn = createCallbackFcn(app, @TextArea_9ValueChanged, true);
            app.TextArea_9.Enable = 'off';
            app.TextArea_9.Value = {'1000'};
            app.Label_15 = uilabel(app.GridLayout);
            app.Label_15.Text = '程序状态';
            app.TextArea_10 = uitextarea(app.GridLayout);
            app.TextArea_10.ValueChangedFcn = createCallbackFcn(app, @TextArea_10ValueChanged, true);
            app.TextArea_10.Value = {'程序未启动，请先设置相关参数'};
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '设置数据集名称';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '终止运行';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end