classdef train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TextArea_19              matlab.ui.control.TextArea
        Label_23                 matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        TextArea_17              matlab.ui.control.TextArea
        Label_22                 matlab.ui.control.Label
        batch_sizeTextArea       matlab.ui.control.TextArea
        batch_sizeTextAreaLabel  matlab.ui.control.Label
        Button_10                matlab.ui.control.Button
        Label_21                 matlab.ui.control.Label
        TextArea_15              matlab.ui.control.TextArea
        Button_9                 matlab.ui.control.Button
        TextArea_14              matlab.ui.control.TextArea
        pythonLabel              matlab.ui.control.Label
        Button_8                 matlab.ui.control.Button
        TextArea_13              matlab.ui.control.TextArea
        Label_20                 matlab.ui.control.Label
        Label_15                 matlab.ui.control.Label
        TextArea_10              matlab.ui.control.TextArea
        Label_18                 matlab.ui.control.Label
        Label_17                 matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        TextArea_11              matlab.ui.control.TextArea
        Button_7                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        workerTextArea           matlab.ui.control.TextArea
        workerTextAreaLabel      matlab.ui.control.Label
        TextArea_5               matlab.ui.control.TextArea
        Button_2                 matlab.ui.control.Button
        TextArea_20              matlab.ui.control.TextArea
        TextArea_20Label         matlab.ui.control.Label
        TextArea_3               matlab.ui.control.TextArea
        Label_10                 matlab.ui.control.Label
        TextArea_18              matlab.ui.control.TextArea
        TextArea_18Label         matlab.ui.control.Label
        GPUDropDown_2            matlab.ui.control.DropDown
        GPUDropDown_2Label       matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        train_display % Description
   
    end
    
    properties (Access = public)
        timenow='0' % Description % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="算法网络模型选取和训练"
        end

        % Callback function
        function ButtonPushed(app, event)
            app.dBTextArea_3.Value=app.TextArea.Value;
        end

        % Callback function
        function ButtonPushed2(app, event)
            source_data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        [fileName,pathName] = uigetfile('*.*','请选择一个文件');%文件筐，选择文件
        if(fileName)
            fileName = strcat(pathName,fileName);
            app.TextArea.Value=fileName;
        end
%             if isequal(filesavepath,0)
%                 disp('User selected Cancel');
%             end
%             if filesavepath~=0
%                 app.TextArea.Value=filesavepath;
%             end
            %path = uigetdir();                            % 用户选择文件夹，取消返回0             
            %[path, name, surffix] = fileparts(fullpath);  % 拆分。
        end

        % Button pushed function: Button
        function ButtonPushed3(app, event)
            %获取参数
            app.TextArea_10.Value="正在训练网络，请勿中断！"+datestr(clock,'yyyy-mm-dd-HH-MM-SS');
            pause(2);
            python_inter=char(app.TextArea_14.Value);
            source=char(app.TextArea.Value);
            target=char(app.TextArea_5.Value);
            weightpath=char(app.TextArea_11.Value);
            trainlog_path=char(app.TextArea_15.Value);
            name=char(app.TextArea_13.Value);
            gpu_id=int16(str2num(char(app.GPUDropDown_2.Value)));
            seed=int16(str2num(char(app.TextArea_3.Value)));
            batch_size=int16(str2num(char(app.batch_sizeTextArea.Value)));
            max_iterations=int16(str2num(char(app.TextArea_20.Value)));
            worker=int16(str2num(char(app.workerTextArea.Value)));
            test_interval=int16(str2num(char(app.TextArea_17.Value)));
            lr=str2num(char(app.TextArea_18.Value));
            class_num=int16(str2num(char(app.TextArea_19.Value)));
            app.TextArea_10.Value="训练进度请查看"+trainlog_path+"文件夹下的训练日志";
            pause(1);
            if app.DropDown.Value=="MMDA-MR"
                command=python_inter+" Non-Partial.py "+"--s_dset_path "+source+" --t_dset_path "+target+" --weigthpath "+weightpath+"\"+" --trainlog_path "+trainlog_path+"\"+ ...,
                    " --name "+name+" --gpu_id "+gpu_id+" --seed "+seed+" --batch_size "+batch_size+" --max_iterations "+max_iterations+" --worker "+worker+ ...,
                    " --test_interval "+test_interval+" --lr "+lr+" --class_num "+class_num;
            elseif app.DropDown.Value=="WMMDA-MR"
                command=python_inter+" Partial.py "+"--s_dset_path "+source+" --t_dset_path "+target+" --weigthpath "+weightpath+"\"+" --trainlog_path "+trainlog_path+"\"+ ...,
                    " --name "+name+" --gpu_id "+gpu_id+" --seed "+seed+" --batch_size "+batch_size+" --max_iterations "+max_iterations+" --worker "+worker+ ...,
                    " --test_interval "+test_interval+" --lr "+lr+" --class_num "+class_num;   
            else
                command=python_inter+" Source_Only.py "+"--s_dset_path "+source+" --t_dset_path "+target+" --weigthpath "+weightpath+"\"+" --trainlog_path "+trainlog_path+"\"+ ...,
                    " --name "+name+" --gpu_id "+gpu_id+" --seed "+seed+" --batch_size "+batch_size+" --max_iterations "+max_iterations+" --worker "+worker+ ...,
                    " --test_interval "+test_interval+" --lr "+lr+" --class_num "+class_num;   
            end
            [status,result]=system(command);
            %cmd_py(python_inter,source,target,weightpath,trainlog_path,name,gpu_id,seed,batch_size,max_iterations,worker,test_interval,lr,class_num)
            app.TextArea_10.Value="训练完成！"+datestr(clock,'yyyy-mm-dd-HH-MM-SS')
            

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Value changed function: TextArea_5
        function TextArea_5ValueChanged(app, event)
            value = app.TextArea_5.Value;
        end

        % Callback function
        function Button_3Pushed(app, event)
            if app.GPUDropDown.Value=="仿真数据"
                if app.DropDown_3.Value=="复杂信道"
                    app.TextArea_5.Value="Sps"+app.spsDropDown.Value+"Len"+app.GPUDropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.GPUTextArea.Value...
                    +"Snr["+app.TextArea_18.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Complex";
                else
                    app.TextArea_5.Value="Sps"+app.spsDropDown.Value+"Len"+app.GPUDropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.GPUTextArea.Value...
                    +"Snr["+app.TextArea_18.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Awgn";
                end   
            else
                    app.TextArea_5.Value="RealData"+"Len"+app.GPUDropDown_2.Value+"Num"+app.GPUTextArea.Value;
            end
        end

        % Drop down opening function: GPUDropDown_2
        function GPUDropDown_2Opening(app, event)
            
        end

        % Value changed function: GPUDropDown_2
        function GPUDropDown_2ValueChanged(app, event)
            value = app.GPUDropDown_2.Value;

        end

        % Callback function
        function HzTextAreaValueChanged(app, event)
            value = app.HzTextArea.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('采样频率输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %uialert(fig,'采样频率输入应为数值！\n请重新输入。','Invalid File');
                %msgbox("采样频率输入应为数值，请重新输入")
            end
        end

        % Callback function
        function HzTextArea_2ValueChanged(app, event)
            value = app.HzTextArea_2.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('中心频率输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("中心频率输入应为数值，请重新输入")
            end
        end

        % Callback function
        function GPUTextAreaValueChanged(app, event)
            value = app.GPUTextArea.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('样本个数输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("样本个数输入应为整数，请重新输入")
            end 
        end

        % Value changed function: TextArea_18
        function TextArea_18ValueChanged(app, event)
            value = app.TextArea_18.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('初始学习率输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("起始信噪比输入应为整数，请重新输入")
            end
        end

        % Callback function
        function dBTextArea_2ValueChanged(app, event)
            value = app.dBTextArea_2.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('终止信噪比输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("终止信噪比输入应为整数，请重新输入")
            end
        end

        % Callback function
        function dBTextArea_3ValueChanged(app, event)
            value = app.dBTextArea_3.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('信噪比步进输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("信噪比步进输入应为整数，请重新输入")
            end
        end

        % Callback function
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value=="复杂信道"
                app.TextArea_20.Enable = 'on';
                app.TextArea_3.Enable = 'on';
                app.workerTextArea.Enable = 'on';
                app.TextArea_8.Enable = 'on';
                app.TextArea_12.Enable="on";  
            else
                app.TextArea_20.Enable = 'off';
                app.TextArea_3.Enable = 'off';
                app.workerTextArea.Enable = 'off';
                app.TextArea_8.Enable = 'off';
                app.TextArea_12.Enable="off";  
            end
        end

        % Callback function
        function GPUDropDownValueChanged(app, event)
            value = app.GPUDropDown.Value;
            if value=="实测数据"
                app.spsDropDown.Enable = 'off';
                app.HzTextArea.Enable = 'off';
                app.HzTextArea_2.Enable = 'off';
                app.GPUTextArea.Enable="off"
                app.TextArea_18.Enable = 'off';
                app.dBTextArea_2.Enable = 'off';
                app.dBTextArea_3.Enable = 'off';
                app.DropDown_3.Enable = 'off';
                app.TextArea_3.Enable = 'off';
                app.TextArea_20.Enable = 'off';
                app.workerTextArea.Enable = 'off';
                app.TextArea_8.Enable = 'off';
                app.Tree.Enable = 'off';
                app.TextArea_9.Enable="on";
                app.TextArea_11.Enable="on";
                app.TextArea_12.Enable="off";
            else
                if app.DropDown_3.Value=="复杂信道"
                    app.spsDropDown.Enable = 'on';
                    app.HzTextArea.Enable = 'on';
                    app.HzTextArea_2.Enable = 'on';
                    app.TextArea_18.Enable = 'on';
                    app.dBTextArea_2.Enable = 'on';
                    app.dBTextArea_3.Enable = 'on';
                    app.DropDown_3.Enable = 'on';
                    app.TextArea_3.Enable = 'on';
                    app.TextArea_20.Enable = 'on';
                    app.workerTextArea.Enable = 'on';
                    app.TextArea_8.Enable = 'on';
                    app.Tree.Enable = 'on';
                    app.GPUTextArea.Enable="on";
                    app.TextArea_9.Enable="off";
                    app.TextArea_11.Enable="off";
                    app.TextArea_12.Enable="on";  
                else
                    app.spsDropDown.Enable = 'on';
                    app.HzTextArea.Enable = 'on';
                    app.HzTextArea_2.Enable = 'on';
                    app.TextArea_18.Enable = 'on';
                    app.dBTextArea_2.Enable = 'on';
                    app.dBTextArea_3.Enable = 'on';
                    app.DropDown_3.Enable = 'on';  
                    app.Tree.Enable = 'on';
                    app.GPUTextArea.Enable="on";
                    app.TextArea_9.Enable="off";
                    app.TextArea_11.Enable="off";
                    app.TextArea_12.Enable="off";
                end
            end
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('随机数种子输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("时钟偏移因子输入应为整数，请重新输入")
            end
        end

        % Value changed function: TextArea_20
        function TextArea_20ValueChanged(app, event)
            value = app.TextArea_20.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('最大迭代次数输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("莱斯信道K因子输入应为整数，请重新输入")
            end
        end

        % Value changed function: workerTextArea
        function workerTextAreaValueChanged(app, event)
            value = app.workerTextArea.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('worker数量输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("时钟偏移因子输入应为整数，请重新输入")
            end
        end

        % Callback function
        function Button_4Pushed(app, event)
            if app.GPUDropDown.Value=="仿真数据"
                if app.DropDown_3.Value=="复杂信道"
                    app.TextArea_5.Value="Sps"+app.spsDropDown.Value+"Len"+app.GPUDropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.GPUTextArea.Value...
                    +"Snr["+app.TextArea_18.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Complex";
                else
                    app.TextArea_5.Value="Sps"+app.spsDropDown.Value+"Len"+app.GPUDropDown_2.Value ...,
                    +"Fs"+app.HzTextArea.Value+"Fc"+app.HzTextArea_2.Value+"Num"+app.GPUTextArea.Value...
                    +"Snr["+app.TextArea_18.Value+","+app.dBTextArea_3.Value+","+app.dBTextArea_2.Value+"]"...
                    +"Awgn";
                end   
            else
                    app.TextArea_5.Value="RealData"+"Len"+app.GPUDropDown_2.Value+"Num"+app.GPUTextArea.Value;
            end
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            %assignin('base','checkedNodes',checkedNodes);
        end

        % Callback function
        function TextArea_9ValueChanged(app, event)
            value = app.TextArea_9.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('每包数据选取样本数输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %uialert(fig,'采样频率输入应为数值！\n请重新输入。','Invalid File');
                %msgbox("采样频率输入应为数值，请重新输入")
            end
        end

        % Value changed function: TextArea_10
        function TextArea_10ValueChanged(app, event)
            value = app.TextArea_10.Value;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
                 app.TextArea_13.Value= char(app.DropDown.Value)+"_BestModelWeight"+"_"+datestr(clock,'yyyy-mm-dd-HH-MM-SS')
        end

        % Callback function
        function Button_6Pushed(app, event)
            global flag
            flag=0;

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            [fileName,pathName] = uigetfile('*.*','请选择一个文件');%文件筐，选择文件
            if(fileName)
                fileName = strcat(pathName,fileName);
                app.TextArea_5.Value=fileName;
            end
            %path = uigetdir();                            % 用户选择文件夹，取消返回0             
            %[path, name, surffix] = fileparts(fullpath);  % 拆分。
        end

        % Value changed function: TextArea_11
        function TextArea_11ValueChanged(app, event)
            value = app.TextArea_11.Value;
            
        end

        % Callback function
        function TextArea_12ValueChanged(app, event)
            value = app.TextArea_12.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('最大多普勒偏移输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            filesavepath = uigetdir('*.m');          % 用户选择文件
            if isequal(filesavepath,0)
                disp('User selected Cancel');
            end
            if filesavepath~=0
                app.TextArea_11.Value=filesavepath;
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            filesavepath = uigetdir('*.m');          % 用户选择文件
            if isequal(filesavepath,0)
                disp('User selected Cancel');
            end
            if filesavepath~=0
                app.TextArea_11.Value=filesavepath;
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            [fileName,pathName] = uigetfile('*.*','请选择一个文件');%文件筐，选择文件
            if(fileName)
                fileName = strcat(pathName,fileName);
                app.TextArea_5.Value=fileName;
            end
        end

        % Value changed function: batch_sizeTextArea
        function batch_sizeTextAreaValueChanged(app, event)
            value = app.batch_sizeTextArea.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('batch_size输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("时钟偏移因子输入应为整数，请重新输入")
            end
        end

        % Value changed function: TextArea_17
        function TextArea_17ValueChanged(app, event)
            value = app.TextArea_17.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('测试间隔输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("起始信噪比输入应为整数，请重新输入")
            end
        end

        % Value changed function: TextArea_19
        function TextArea_19ValueChanged(app, event)
            value = app.TextArea_19.Value;
            [X,tf] = str2num(value{1});
            if tf == 0
                fig = uifigure;
                message = sprintf('源域种类数输入应为数值！\n请重新输入。');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                %msgbox("时钟偏移因子输入应为整数，请重新输入")
            end
        end

        % Value changed function: TextArea_14
        function TextArea_14ValueChanged(app, event)
            value = app.TextArea_14.Value;
            
        end

        % Value changed function: TextArea_15
        function TextArea_15ValueChanged(app, event)
            value = app.TextArea_15.Value;
            
        end

        % Value changed function: TextArea_13
        function TextArea_13ValueChanged(app, event)
            value = app.TextArea_13.Value;
            
        end

        % Callback function
        function Button_11Pushed(app, event)
            app.train_display=train_display(app,app.TextArea_15.Value);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '算法网络模型选取和训练';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'E:\software\DataSet\sps8_len512_num1000_train_ori.h5'};
            app.GPUDropDown_2Label = uilabel(app.GridLayout);
            app.GPUDropDown_2Label.Text = 'GPU编号';
            app.GPUDropDown_2 = uidropdown(app.GridLayout);
            app.GPUDropDown_2.Items = {'0', '1', '2', '3', '4', '5'};
            app.GPUDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @GPUDropDown_2Opening, true);
            app.GPUDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GPUDropDown_2ValueChanged, true);
            app.GPUDropDown_2.Value = '0';
            app.TextArea_18Label = uilabel(app.GridLayout);
            app.TextArea_18Label.Text = '初始学习率';
            app.TextArea_18 = uitextarea(app.GridLayout);
            app.TextArea_18.ValueChangedFcn = createCallbackFcn(app, @TextArea_18ValueChanged, true);
            app.TextArea_18.Value = {'2e-3'};
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '随机数种子';
            app.TextArea_3 = uitextarea(app.GridLayout);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.Value = {'2021'};
            app.TextArea_20Label = uilabel(app.GridLayout);
            app.TextArea_20Label.Text = '最大迭代次数';
            app.TextArea_20 = uitextarea(app.GridLayout);
            app.TextArea_20.ValueChangedFcn = createCallbackFcn(app, @TextArea_20ValueChanged, true);
            app.TextArea_20.Value = {'1'};
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选取';
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.ValueChangedFcn = createCallbackFcn(app, @TextArea_5ValueChanged, true);
            app.TextArea_5.Value = {'E:\software\DataSet\sps8_len512_num400_test_ori.h5'};
            app.workerTextAreaLabel = uilabel(app.GridLayout);
            app.workerTextAreaLabel.Text = 'worker数量';
            app.workerTextArea = uitextarea(app.GridLayout);
            app.workerTextArea.ValueChangedFcn = createCallbackFcn(app, @workerTextAreaValueChanged, true);
            app.workerTextArea.Value = {'1'};
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '一键设置';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed3, true);
            app.Button.Icon = '启动.svg';
            app.Button.Text = '启动训练';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '选取';
            app.TextArea_11 = uitextarea(app.GridLayout);
            app.TextArea_11.ValueChangedFcn = createCallbackFcn(app, @TextArea_11ValueChanged, true);
            app.TextArea_11.Value = {'E:\software\ModelWeight'};
            app.Label_16 = uilabel(app.GridLayout);
            app.Label_16.Text = '训练调制数据';
            app.Label_17 = uilabel(app.GridLayout);
            app.Label_17.Text = '测试调制数据';
            app.Label_18 = uilabel(app.GridLayout);
            app.Label_18.Text = '模型权重保存路径';
            app.TextArea_10 = uitextarea(app.GridLayout);
            app.TextArea_10.ValueChangedFcn = createCallbackFcn(app, @TextArea_10ValueChanged, true);
            app.TextArea_10.Value = {'程序未启动，请先设置相关参数'};
            app.Label_15 = uilabel(app.GridLayout);
            app.Label_15.Text = '程序状态';
            app.Label_20 = uilabel(app.GridLayout);
            app.Label_20.Text = '模型权重和日志名称';
            app.TextArea_13 = uitextarea(app.GridLayout);
            app.TextArea_13.ValueChangedFcn = createCallbackFcn(app, @TextArea_13ValueChanged, true);
            app.TextArea_13.Value = {'MMDA-MR_BestModelWeight_2021-10-02-16-39-14'};
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '设置';
            app.pythonLabel = uilabel(app.GridLayout);
            app.pythonLabel.Text = 'python解释器';
            app.TextArea_14 = uitextarea(app.GridLayout);
            app.TextArea_14.ValueChangedFcn = createCallbackFcn(app, @TextArea_14ValueChanged, true);
            app.TextArea_14.Value = {'D:\Anaconda3\envs\torch\python.EXE'};
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '选取';
            app.TextArea_15 = uitextarea(app.GridLayout);
            app.TextArea_15.ValueChangedFcn = createCallbackFcn(app, @TextArea_15ValueChanged, true);
            app.TextArea_15.Value = {'E:\software\TrainLog'};
            app.Label_21 = uilabel(app.GridLayout);
            app.Label_21.Text = '日志保存路径';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '设置';
            app.batch_sizeTextAreaLabel = uilabel(app.GridLayout);
            app.batch_sizeTextAreaLabel.Text = 'batch_size';
            app.batch_sizeTextArea = uitextarea(app.GridLayout);
            app.batch_sizeTextArea.ValueChangedFcn = createCallbackFcn(app, @batch_sizeTextAreaValueChanged, true);
            app.batch_sizeTextArea.Value = {'400'};
            app.Label_22 = uilabel(app.GridLayout);
            app.Label_22.Text = '测试间隔';
            app.TextArea_17 = uitextarea(app.GridLayout);
            app.TextArea_17.ValueChangedFcn = createCallbackFcn(app, @TextArea_17ValueChanged, true);
            app.TextArea_17.Value = {'1'};
            app.DropDownLabel = uilabel(app.GridLayout);
            app.DropDownLabel.Text = '算法模型选取';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'MMDA-MR', 'WMMDA-MR', 'IQ+SPC+AP-Source_Only'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'MMDA-MR';
            app.Label_23 = uilabel(app.GridLayout);
            app.Label_23.Text = '源域种类数';
            app.TextArea_19 = uitextarea(app.GridLayout);
            app.TextArea_19.ValueChangedFcn = createCallbackFcn(app, @TextArea_19ValueChanged, true);
            app.TextArea_19.Value = {'6'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = train

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end